classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCTORFrameworkUIFigure        matlab.ui.Figure
        HelpMenu                      matlab.ui.container.Menu
        AboutUsMenu                   matlab.ui.container.Menu
        TutorialMenu                  matlab.ui.container.Menu
        RequirementsMenu              matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        NumberOfObjectivesDropDown    matlab.ui.control.DropDown
        NumberofObjectivesDropDownLabel  matlab.ui.control.Label
        EncodingDropDown              matlab.ui.control.DropDown
        EncodingDropDownLabel         matlab.ui.control.Label
        PopulationSizeEditField       matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel  matlab.ui.control.Label
        VersionLabel                  matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        AlgorithmsPropertiesLabel     matlab.ui.control.Label
        LoadTGFFFileButton            matlab.ui.control.Button
        WeightEditField               matlab.ui.control.EditField
        WeightEditFieldLabel          matlab.ui.control.Label
        TargetEditField               matlab.ui.control.EditField
        TargetEditFieldLabel          matlab.ui.control.Label
        SourceEditField               matlab.ui.control.EditField
        SourceEditFieldLabel          matlab.ui.control.Label
        TasksAmountEditField          matlab.ui.control.NumericEditField
        TasksAmountEditFieldLabel     matlab.ui.control.Label
        TasksGraphLabel               matlab.ui.control.Label
        ColumnsEditField              matlab.ui.control.NumericEditField
        ColumnsEditField_2Label       matlab.ui.control.Label
        RowsEditField                 matlab.ui.control.NumericEditField
        RowsEditField_2Label          matlab.ui.control.Label
        ProcessorsGridLabel           matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Graph                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        popSize    = 100;   % Population Size
        numObj     = 2;     % Number of objectives (Energy and Fault-tolerance)
        numRows    = 4;  % Processors Grid: rows
        numColumns = 4;  % Processors Grid: columns
        numTasks   = 5;  % Tasks Graph: number of tasks
        sourceIds  = [1 1 2 3 3 4]; % Tasks Graph: source nodes ids
        targetIds  = [2 3 4 4 5 5]; % Tasks Graph: target nodes ids
        weights    = [10 20 30 40 50 60]; % Tasks Graph: weights
        encoding   = 6; % Encoding scheme of each decision variable
        
        % Structure to save the solution data
        solution;
        % Figure to represent the cromossome solution
        g;

        % Control flags
        loadFromFile = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            addpath(genpath([pwd,'\PlatEMO_v4_1']))
            
            % Check user parameters' consistency
            if (app.numRows * app.numColumns) < app.numTasks
                uiwait(msgbox("The number of tasks exceeds the total number of routers/processors available in the grid!", "Error"));
            else
                % Assembly the parameters
                params = {app.numTasks, app.numRows, app.numColumns, app.popSize, app.numObj, app.sourceIds, app.targetIds, app.weights, app.encoding};
                
                app.RUNButton.Enable = "off";
                
                % Call PlatEMO
                [dec, obj, con] = platemo('problem', @ManyCoreV1, 'algorithm', @NSGAII, 'parameter', params, 'save', 0);
                
                % Create a struct containing the solution
                app.solution = utils.structureSolution(dec, obj, con);
    
                dcm = datacursormode(app.MCTORFrameworkUIFigure);
                dcm.Enable = 'on';
                dcm.UpdateFcn = {@utils.displaySolutionTip,app};
                
                % Plot the Pareto-front curve
                plot(app.Graph, app.solution.energy, app.solution.fault_tolerance, '.', 'MarkerSize', 22);
                
                app.RUNButton.Enable = "on";
            end
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.numColumns = app.ColumnsEditField.Value;
            utils.log(['[Grid] Columns: ' num2str(app.numColumns)]);
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.numRows = app.RowsEditField.Value;
            utils.log(['[Grid] Rows: ' num2str(app.numRows)]);
        end

        % Value changed function: TasksAmountEditField
        function TasksAmountEditFieldValueChanged(app, event)
            app.numTasks = app.TasksAmountEditField.Value;
            utils.log(['[Graph] Number of Tasks: ' num2str(app.numTasks)]);
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Source Tasks: ' value]);
                app.sourceIds = numArray;
            end
        end

        % Value changed function: TargetEditField
        function TargetEditFieldValueChanged(app, event)
            value = app.TargetEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Target Tasks: ' value]);
                app.targetIds = numArray;
            end
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Edge Weights: ' value]);
                app.weights = numArray;
            end
        end

        % Value changed function: EncodingDropDown
        function EncodingDropDownValueChanged(app, event)
            value = app.EncodingDropDown.Value;
            switch value
                case '1.Real'
                    app.encoding = 1;
                case '2.Integer'
                    app.encoding = 2;
                case '3.Label'
                    app.encoding = 3;
                case '4.Binary'
                    app.encoding = 4;
                case '5.Permutation'
                    app.encoding = 5;
                case '6.User-defined'
                    app.encoding = 6;
            end
            utils.log(['Encoding: ' num2str(app.encoding)]);
        end

        % Button pushed function: LoadTGFFFileButton
        function LoadTGFFFileButtonPushed(app, event)
            % Open dialog box to load file
            [file, path] = uigetfile({'*.tgff', '*.vcg'},'Select the TGFF files',pwd,'MultiSelect','on');
            if isequal(file, 0)
                app.loadFromFile = false;
                utils.log('User selected cancel');
            else
                
                fullPath = fullfile(path,file);
                utils.log(['User selected ', fullPath]);
                
                % Convert the Graphs from TGFF format to
                % Source-Target-Weight arrays format
                [n, s, t, w, g] = parseTGFF(fullPath);
                
                app.numTasks  = n;
                app.sourceIds = s;
                app.targetIds = t;
                app.weights   = w;
                
                app.TasksAmountEditField.Value = n;
                app.SourceEditField.Value = sprintf('%d ', s);
                app.TargetEditField.Value = sprintf('%d ', t);
                app.WeightEditField.Value = sprintf('%0.2f ', w);
                
                utils.log(['Number of Tasks: ' app.TasksAmountEditField.Value]);
                utils.log(['Source: ' app.SourceEditField.Value]);
                utils.log(['Target: ' app.TargetEditField.Value]);
                utils.log(['Weight: ' app.WeightEditField.Value]);
                app.loadFromFile = true;
            end
        end

        % Callback function
        function GraphButtonDown(app, event)
             utils.getSelectedPoint(app, event);
        end

        % Callback function: not associated with a component
        function NumberOfObjectivesEditFieldValueChanged(app, event)
            app.numObj = app.NumberOfObjectivesEditField.Value;
            utils.log(['Number of objectives: ' num2str(app.numObj)]);
        end

        % Value changed function: PopulationSizeEditField
        function PopulationSizeEditFieldValueChanged(app, event)
            app.popSize = app.PopulationSizeEditField.Value;
            utils.log(['Population Size: ' num2str(app.popSize)]);
        end

        % Value changed function: NumberOfObjectivesDropDown
        function NumberOfObjectivesDropDownValueChanged(app, event)
            value = app.NumberOfObjectivesDropDown.Value;
            switch value
                case '2'
                    app.numObj = 2;
                case '3'
                    app.numObj = 3;
            end
            utils.log(['Number of objectives: ' num2str(app.numObj)]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MCTORFrameworkUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCTORFrameworkUIFigure = uifigure('Visible', 'off');
            app.MCTORFrameworkUIFigure.Name = 'MCTOR Framework';
            app.MCTORFrameworkUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.MCTORFrameworkUIFigure.Tag = 'v1.0';
            app.HelpMenu = uimenu(app.MCTORFrameworkUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutUsMenu = uimenu(app.HelpMenu);
            app.AboutUsMenu.Text = 'About us';
            app.TutorialMenu = uimenu(app.HelpMenu);
            app.TutorialMenu.Text = 'Tutorial';
            app.RequirementsMenu = uimenu(app.HelpMenu);
            app.RequirementsMenu.Text = 'Requirements';
            app.GridLayout = uigridlayout(app.MCTORFrameworkUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ProcessorsGridLabel = uilabel(app.LeftPanel);
            app.ProcessorsGridLabel.Text = 'Processors Grid';
            app.RowsEditField_2Label = uilabel(app.LeftPanel);
            app.RowsEditField_2Label.Text = 'Rows';
            app.RowsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 4;
            app.ColumnsEditField_2Label = uilabel(app.LeftPanel);
            app.ColumnsEditField_2Label.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 4;
            app.TasksGraphLabel = uilabel(app.LeftPanel);
            app.TasksGraphLabel.Text = 'Tasks Graph';
            app.TasksAmountEditFieldLabel = uilabel(app.LeftPanel);
            app.TasksAmountEditFieldLabel.Text = 'Tasks Amount';
            app.TasksAmountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TasksAmountEditField.Limits = [1 Inf];
            app.TasksAmountEditField.ValueChangedFcn = createCallbackFcn(app, @TasksAmountEditFieldValueChanged, true);
            app.TasksAmountEditField.Value = 5;
            app.SourceEditFieldLabel = uilabel(app.LeftPanel);
            app.SourceEditFieldLabel.Text = 'Source';
            app.SourceEditField = uieditfield(app.LeftPanel, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.SourceEditField.Value = '1 1 2 3 3 4';
            app.TargetEditFieldLabel = uilabel(app.LeftPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.LeftPanel, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField.Value = '2 3 4 4 5 5';
            app.WeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.LeftPanel, 'text');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = '10 20 30 40 50 60';
            app.LoadTGFFFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadTGFFFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTGFFFileButtonPushed, true);
            app.LoadTGFFFileButton.Text = 'Load TGFF File';
            app.AlgorithmsPropertiesLabel = uilabel(app.LeftPanel);
            app.AlgorithmsPropertiesLabel.Text = 'Algorithms Properties';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.VersionLabel = uilabel(app.LeftPanel);
            app.VersionLabel.Text = 'v1.0 2023';
            app.PopulationSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PopulationSizeEditField.Limits = [1 Inf];
            app.PopulationSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopulationSizeEditFieldValueChanged, true);
            app.PopulationSizeEditField.Value = 100;
            app.EncodingDropDownLabel = uilabel(app.LeftPanel);
            app.EncodingDropDownLabel.Text = 'Encoding';
            app.EncodingDropDown = uidropdown(app.LeftPanel);
            app.EncodingDropDown.Items = {'1.Real', '2.Integer', '3.Label', '4.Binary', '5.Permutation', '6.User-defined'};
            app.EncodingDropDown.ValueChangedFcn = createCallbackFcn(app, @EncodingDropDownValueChanged, true);
            app.EncodingDropDown.Value = '6.User-defined';
            app.NumberofObjectivesDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofObjectivesDropDownLabel.Text = 'Number of Objectives';
            app.NumberOfObjectivesDropDown = uidropdown(app.LeftPanel);
            app.NumberOfObjectivesDropDown.Items = {'2', '3'};
            app.NumberOfObjectivesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberOfObjectivesDropDownValueChanged, true);
            app.NumberOfObjectivesDropDown.Value = '2';
            app.RightPanel = uipanel(app.GridLayout);
            app.Graph = uiaxes(app.RightPanel);
            xlabel(app.Graph, 'Energy')
            ylabel(app.Graph, 'Fault tolerance')
            zlabel(app.Graph, 'Load balance')
            % Show the figure after all components are created
            app.MCTORFrameworkUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCTORFrameworkUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCTORFrameworkUIFigure)
        end
    end
end