classdef mctor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCTORFrameworkUIFigure        matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Single                        matlab.ui.container.Tab
        LeftPanel                     matlab.ui.container.Panel
        AlgorithmDropDownLabel        matlab.ui.control.Label
        TasksAmountEditFieldLabel     matlab.ui.control.Label
        ViewGraphButton               matlab.ui.control.Button
        GraphsInputLabel              matlab.ui.control.Label
        LoadFromFileButton            matlab.ui.control.Button
        WeightEditField               matlab.ui.control.EditField
        TargetEditField               matlab.ui.control.EditField
        SourceEditField               matlab.ui.control.EditField
        TasksAmountEditField          matlab.ui.control.EditField
        WeightEditFieldLabel          matlab.ui.control.Label
        TargetEditFieldLabel          matlab.ui.control.Label
        SourceEditFieldLabel          matlab.ui.control.Label
        VersionLabel                  matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        GridSizeLabel                 matlab.ui.control.Label
        RowsEditField                 matlab.ui.control.NumericEditField
        ColumnsEditField              matlab.ui.control.NumericEditField
        ProblemPropertiesLabel        matlab.ui.control.Label
        ApproachDropDownLabel         matlab.ui.control.Label
        ApproachDropDown              matlab.ui.control.DropDown
        PopulationSizeEditField       matlab.ui.control.NumericEditField
        MutationRateEditField         matlab.ui.control.NumericEditField
        MutationRateEditFieldLabel    matlab.ui.control.Label
        NumberOfObjectivesDropDown    matlab.ui.control.DropDown
        NumberofObjectivesDropDownLabel  matlab.ui.control.Label
        PopulationSizeEditFieldLabel  matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        ModeSelect                    matlab.ui.container.ButtonGroup
        SingleButton                  matlab.ui.control.ToggleButton
        MultiButton                   matlab.ui.control.ToggleButton
        ColumnsEditFieldLabel         matlab.ui.control.Label
        RowsEditFieldLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Graph                         matlab.ui.control.UIAxes
        Batch                         matlab.ui.container.Tab
        LeftPanel_Batch               matlab.ui.container.Panel
        GraphsInputLabel_2            matlab.ui.control.Label
        TasksAmountEditFieldLabel_2   matlab.ui.control.Label
        TasksAmountEditField_2        matlab.ui.control.EditField
        SourceEditFieldLabel_2        matlab.ui.control.Label
        SourceEditField_2             matlab.ui.control.EditField
        TargetEditFieldLabel_2        matlab.ui.control.Label
        TargetEditField_2             matlab.ui.control.EditField
        WeightEditFieldLabel_2        matlab.ui.control.Label
        WeightEditField_2             matlab.ui.control.EditField
        LoadFromFileButton_2          matlab.ui.control.Button
        ViewGraphButton_2             matlab.ui.control.Button
        BatchParametersLabel          matlab.ui.control.Label
        ModeSelect_2                  matlab.ui.container.ButtonGroup
        SingleButton_2                matlab.ui.control.ToggleButton
        MultiButton_2                 matlab.ui.control.ToggleButton
        GridSizeTextArea_Batch        matlab.ui.control.TextArea
        AlgorithmTextAreaLabel        matlab.ui.control.Label
        PopulationsizeLabel           matlab.ui.control.Label
        RowsColumnsTextAreaLabel      matlab.ui.control.Label
        NumberOfExecutionsEditField   matlab.ui.control.NumericEditField
        PopulationSizeEditField_2Label_2  matlab.ui.control.Label
        MutationrateTextAreaLabel     matlab.ui.control.Label
        ApproachTextArea              matlab.ui.control.TextArea
        ApproachTextAreaLabel         matlab.ui.control.Label
        PopulationSizeTextArea_Batch  matlab.ui.control.TextArea
        MutationRateTextArea          matlab.ui.control.TextArea
        AlgorithmTextArea             matlab.ui.control.TextArea
        RUNButton_Batch               matlab.ui.control.Button
        STOPButton_Batch              matlab.ui.control.Button
        VersionLabel_2                matlab.ui.control.Label
        RightPanel_Batch              matlab.ui.container.Panel
        VisualizeSolutionButton       matlab.ui.control.Button
        ExportResultsButton           matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        AboutUsTab                    matlab.ui.container.Tab
        Title                         matlab.ui.control.Label
        AuthorsLabel                  matlab.ui.control.Label
        AuthorsList                   matlab.ui.control.Label
        SoftwareVersionLabel          matlab.ui.control.Label
        VersionsList                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        rootFolder = '';
        matlabVersion = 0;

        %% Single mode panel
        popSize    = 100;   % Population Size
        objList    = ["E","LB"]; % List of objectives options (Energy and Load-balance)
        numRows    = 4;  % Processors Grid: rows
        numColumns = 4;  % Processors Grid: columns
        mutationRateSingle = 0.01;  % Mutation Rate
        encoding   = 6; % Encoding scheme of each decision variable
        algtype = "EV";
        algorithm  = "NSGAII";
        algorithmVariation = 0;
        approach = "Multi-Application";
        maxFE = 50000;
        applicationMode = "SINGLE";

        %% Batch mode panel
        inputListBatch = ["sample"];
        numExecutions = 50;
        encodingBatch = 6;
        gridSizeBatch = [4 4; 6 6; 8 8];
        popSizeBatch = [100];
        mutationRateBatch = [0.01];
        algorithmsBatch = ["NSGAII"];
        approachesBatch = ["Multi-Application"];
        applicationModeBatch = "SINGLE";
        batchResults;
        paramsToSave; % The table parameters to save in the batch results
        waitBar;

        %% TGFF/Graphs
        numTasks   = [5];           % Input Graphs: total number of tasks for each application
        sourceIds  = [1 1 2 3 3 4]; % Input Graphs: source nodes ids
        targetIds  = [2 3 4 4 5 5]; % Input Graphs: target nodes ids
        weights    = [10 20 30 40 50 60]; % Input Graphs: arc weights
        applications;% = struct('applicationName', 'sample', 'numTasks', numTasks, 'sourceIds', sourceIds, 'targetIds', targetIds, 'weights', weights);
        
        %% Common structures
        % Structure to save the solution data
        solution;
        % Figure to represent the chromosome solution
        g;

        %% Control flags
        isStopped = false;
        fileSelected = "sample";
    end
    
    methods (Access = private)
        
        %% Load TGFF and APP files
        function [n, s, t, w, nt, status] = loadFile(app, fullPath, ext)
            if ext == ".tgffopt"
                % Call TGFF conversor
                input_file = strrep(fullPath,'.tgffopt','');
                output_file = strrep(fullPath,'.tgffopt','.tgff');
                command = [app.rootFolder,'\tgff_files\tgff3_1.exe',' ', input_file];
                [status, cmd_out] = system(command);
                
                if status == 0
                    metaConfig = tgff.extractMetaInfo(fullPath);
                    % Convert the Graphs from TGFF format to
                    % Source-Target-Weight arrays format
                    [n, s, t, w, nt] = tgff.parse(output_file, metaConfig);
                else
                    msgbox({"The TGFF conversor has failed!"; "Please, check the parameters in the .tgffopt file"; strcat("Status: ",cmd_out) }, "TGFF conversion failed");
                end
            elseif ext == ".app"
                 % Convert the Graphs from APP format to Source-Target-Weight arrays format
                 [n, s, t, w, nt] = file_loader.parse(fullPath);
                 status = 0;
            else
                msgbox(strcat("The extension ",ext," is not supported!"), "File extension not supported");
            end
        end
        
        %% Call the Files Selector Box and load applications from the selected files
        function [] = loadFromFile(app, event, batchMode)
            app.ViewGraphButton.Enable = 'off';
            app.ViewGraphButton_2.Enable = 'off';

            % Open dialog box to load file
            multiSelectMode = 'off';
            dialogBoxMsg = 'Select the input file(s)';
            if batchMode || strcmp(app.applicationMode,'MULTI')
                multiSelectMode = 'on';
            end

            [file, path] = uigetfile({'*.tgffopt;*.app'},dialogBoxMsg,app.rootFolder,'MultiSelect',multiSelectMode);
            
            if isfloat(file)
                utils.log('User selected cancel');
            else
                fullPath = fullfile(path, file);
                [~, app.fileSelected, ext] = fileparts(fullPath);
                utils.log(['User selected ', fullPath]);
                
                if isstr(app.fileSelected)
                    % Single file selected
                    fullPath = {fullPath};
                    ext = {ext};
                    app.inputListBatch = {app.fileSelected};
                    numFiles = 1;
                else
                    % Multiple files selected
                    app.inputListBatch = app.fileSelected;
                    numFiles = length(app.fileSelected);
                end
                
                % Initialize array of structures
                app.applications = utils.initializeApplication(numFiles);

                for i=1:numFiles

                    [n, s, t, w, nt, status] = loadFile(app, fullPath{1,i}, ext{1,i});
    
                    if status == 0
                        appName = app.inputListBatch{1,i};

                        app.applications(i).applicationName = appName;
                        app.applications(i).numTasks        = nt;
                        app.applications(i).sourceIds       = s;
                        app.applications(i).targetIds       = t;
                        app.applications(i).weights         = w;
                                                
                        app.TasksAmountEditField.Value = sprintf('%d ', nt);
                        app.TasksAmountEditField_2.Value = sprintf('%d ', nt);
                        app.SourceEditField.Value = sprintf('%d ', s);
                        app.SourceEditField_2.Value = sprintf('%d ', s);
                        app.TargetEditField.Value = sprintf('%d ', t);
                        app.TargetEditField_2.Value = sprintf('%d ', t);
                        app.WeightEditField.Value = sprintf('%0.2f ', w);
                        app.WeightEditField_2.Value = sprintf('%0.2f ', w);
                        
                        utils.log(['Application name: ' appName]);
                        utils.log(['Number of Tasks: ' num2str(app.TasksAmountEditField.Value)]);
                        utils.log(['Source: ' app.SourceEditField.Value]);
                        utils.log(['Target: ' app.TargetEditField.Value]);
                        utils.log(['Weight: ' app.WeightEditField.Value]);
                    end
                end
                utils.log('All selected Applications were successfully loaded!');

            end
            app.ViewGraphButton.Enable = 'on';
            app.ViewGraphButton_2.Enable = 'on';
        end
        
        %% Visualize the applications graphs
        function [] = viewGraph(app)
            s = app.applications(1).sourceIds;
            t = app.applications(1).targetIds;
            w = app.applications(1).weights;

            if ~isempty(s) && ~isempty(t)
                f = figure;
                set(f, 'NumberTitle', 'off', 'Name', 'Graphs');
                g = digraph(s,t,w);
                plot(g,'EdgeLabel',g.Edges.Weight,'Marker','o','MarkerSize',8);
            end
        end

        %% Update a waitbar using the UserData property.
        function updateWaitbar(app, w)        
            % Check if the waitbar is a reference to a deleted object
            if isvalid(w)
                % Increment the number of completed iterations 
                w.UserData(1) = w.UserData(1) + 1;
                % Calculate the progress
                progress = w.UserData(1) / w.UserData(2);
                % Update the waitbar
                waitbar(progress,w);
            end
        end
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.MCTORFrameworkUIFigure.WindowState = 'maximized';
            app.rootFolder = pwd;
            addpath(app.rootFolder);
            % addpath(genpath([app.rootFolder,'\resources']));
            addpath(genpath([app.rootFolder,'\tgff_files']));
            addpath(genpath([app.rootFolder,'\thirdparty']));
            addpath(genpath([app.rootFolder,'\PlatEMO']));
            addpath(genpath([app.rootFolder,'\MultiPhase']));
            addpath(genpath([app.rootFolder,'\SimulatedAnnealing']));
            addpath(genpath([app.rootFolder,'\Condor']));
            
            v = version('-release');
            app.matlabVersion = str2double(v(1:4));

            % Initialize array of structures
            app.applications = utils.initializeApplication(1);
            app.applications(1).applicationName = "default";
            app.applications(1).numTasks        = app.numTasks;
            app.applications(1).sourceIds       = app.sourceIds;
            app.applications(1).targetIds       = app.targetIds;
            app.applications(1).weights         = app.weights;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % Assembly the parameters
            if strcmp(app.applicationModeBatch,'MULTI')
                [nT, s, t, w] = utils.splitApps(app);
                params = {app.algorithm, nT, app.numRows, app.numColumns, app.popSize, app.objList, s, t, w, app.encoding, app.algorithmVariation, app.mutationRateSingle, app.maxFE};
            else
                % Retrieve the current Application Graphs
                currentApp = app.applications(1);
                params = {app.algorithm, sum(currentApp.numTasks), app.numRows, app.numColumns, app.popSize, app.objList, currentApp.sourceIds, currentApp.targetIds, currentApp.weights, app.encoding, app.algorithmVariation, app.mutationRateSingle, app.maxFE};
            end

            % app.RUNButton.Enable = "off";
            
            % Call PlatEMO
            if ~strcmp(app.algtype, "EV")
                dec = MCMAPopInitV2(params{5}, params{2}, params{3}, params{4}, app.algorithmVariation);
                obj = MCMACustoV2(dec, params{7}, params{8}, params{9}, params{3}, params{4}, params{6}(1));
            else
                [dec, obj] = utils.callApproachByOption(app, app.approach, params);
            end

            cd('..');

            % Create a struct containing the solution
            app.solution = utils.structureSolution(dec, obj);

            dcm = datacursormode(app.MCTORFrameworkUIFigure);
            dcm.Enable = "on";
            dcm.UpdateFcn = {@utils.displaySolutionTip,app};
            
            % Distinct the best solution (based on the euclidian distance
            % to the origin) from the others
            group = repmat("others", [app.popSize, 1]);
            group(app.solution.best.all_best_index) = "best";            
            if group(1) == "others"
                groupColor = 'br';
            else
                groupColor = 'rb';
            end
            
            % Plot the Pareto-front curve
            % plot(app.Graph, app.solution.x_axis, app.solution.y_axis, '.', 'MarkerSize', 22);
            gscatter(app.Graph, app.solution.x_axis, app.solution.y_axis, group,groupColor,'.',[18 22],'on');
            utils.setGraphicScale(app.Graph, app.solution.x_axis, app.solution.y_axis);
            
            app.RUNButton.Enable = "on";
        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            app.numColumns = app.ColumnsEditField.Value;
            utils.log(['[Grid] Columns: ' num2str(app.numColumns)]);
        end

        % Value changed function: RowsEditField
        function RowsEditFieldValueChanged(app, event)
            app.numRows = app.RowsEditField.Value;
            utils.log(['[Grid] Rows: ' num2str(app.numRows)]);
        end

        % Value changed function: TasksAmountEditField
        function TasksAmountEditFieldValueChanged(app, event)
            value = app.TasksAmountEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Number of Tasks: ' value]);
                app.numTasks = numArray;
            end
        end

        % Value changed function: SourceEditField
        function SourceEditFieldValueChanged(app, event)
            value = app.SourceEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Source Tasks: ' value]);
                app.sourceIds = numArray;
            end
        end

        % Value changed function: TargetEditField
        function TargetEditFieldValueChanged(app, event)
            value = app.TargetEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Target Tasks: ' value]);
                app.targetIds = numArray;
            end
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            numArray = str2double(value);
            if isnumeric(numArray)
                utils.log(['[Graph] Edge Weights: ' value]);
                app.weights = numArray;
            end
        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed(app, event)
            loadFromFile(app, event, false);
        end

        % Button pushed function: LoadFromFileButton_2
        function LoadFromFileButton_2Pushed(app, event)
            loadFromFile(app, event, true);
        end

        % Value changed function: NumberOfObjectivesDropDown
        function NumberOfObjectivesDropDownValueChanged(app, event)
            value = app.NumberOfObjectivesDropDown.Value;
            app.objList = utils.getObjectivesDropDown(value);

            switch length(app.objList)
                case 1
                    utils.setAxisLabel(app.Graph, 'x', 'elements');
                    utils.setAxisLabel(app.Graph, 'y', app.objList(1));
                case 2
                    utils.setAxisLabel(app.Graph, 'x', app.objList(1));
                    utils.setAxisLabel(app.Graph, 'y', app.objList(2));
                case 3
                    utils.setAxisLabel(app.Graph, 'x', app.objList(1));
                    utils.setAxisLabel(app.Graph, 'y', app.objList(2));
                    utils.setAxisLabel(app.Graph, 'z', app.objList(3));
            end
            
            utils.log(['Objectives: ' app.objList]);
        end

        % Value changed function: PopulationSizeEditField
        function PopulationSizeEditFieldValueChanged(app, event)
            app.popSize = app.PopulationSizeEditField.Value;
            utils.log(['Population Size: ' num2str(app.popSize)]);
        end

        % Button pushed function: RUNButton_Batch
        function RUNButton_BatchPushed(app, event)
            % Initialize UITable and get parameters
            paramsGrid = utils.initializeTable(app, app.UITable);
            pause(1);
            
            % app.RUNButton.Enable = "off";
            % app.RUNButton_Batch.Enable = "off";
            app.isStopped = false;

            n = paramsGrid.n;

            % Structure to store the batch execution results
            batch_results = cell(n, 1);
            
            % Initialize Progress bar
            if ~isempty(app.waitBar) && isvalid(app.waitBar)
                close(app.waitBar);
            end
            app.waitBar = waitbar(0, 'Executing tests...');
            app.waitBar.UserData = [0 n];
            
            numExecuted = 0;
            
            numObjectives = length(app.objList);

            % Perform the batch execution
            for i = 1:n
                best_objectives = zeros(app.numExecutions, numObjectives);
                best_solutions = cell(app.numExecutions,1);
                best_solutions_ttot = zeros(app.numExecutions,1);

                % Assembly the parameters
                [type, alg, var] = utils.getAlgorithmDropDown(paramsGrid.alg{i,1});
                approach_option = paramsGrid.approach{i,1};
                
                if strcmp(app.applicationModeBatch,'MULTI')
                    % Retrieve the current Application Graphs
                    [nT, s, t, w] = utils.concatApps(app);
                    params = {sum(nT), paramsGrid.r(i), paramsGrid.c(i), paramsGrid.pop(i), app.objList, s, t, w, app.encodingBatch, var, paramsGrid.mr(i)};
                else
                    currentApp = app.applications(paramsGrid.appIdx(i));
                    params = {sum(currentApp.numTasks), paramsGrid.r(i), paramsGrid.c(i), paramsGrid.pop(i), app.objList, currentApp.sourceIds, currentApp.targetIds, currentApp.weights, app.encodingBatch, var, paramsGrid.mr(i)};
                end

                % Execute @numExecutions times
                parfor j = 1:app.numExecutions
                    if ~strcmp(type, "EV")
                        dec = MCMAPopInitV2(params{4}, params{1}, params{2}, params{3}, var);
                        obj = MCMACustoV2(dec, params{6}, params{7}, params{8}, params{2}, params{3}, params{5}(1));
                    else
                        [dec, obj] = platemo('problem', @ManyCoreMAV1, 'algorithm', str2func(alg), 'maxFE', app.maxFE, 'parameter', params, 'save', 0);
                    end

                    % [dec, obj] = utils.callApproachByOption(app, approach_option, params);
                    b = utils.getBestSolution(dec, obj);
                    best_objectives(j,:) = b.best_result;
                    best_solutions{j,1} = b.best_solution;
                    % Calculate the Total Time (theorical)
                    best_solutions_ttot(j) = utils.getTotalTime(b.best_solution, params{1,2}, params{1,3}, params{1,6}, params{1,7}, params{1,8});
                end
                
                stats = utils.getStatistics(app.objList, best_objectives, best_solutions_ttot);
                
                % Update UITable the objectives
                obj1Label = strcat(compose("%.4f",stats.mean_1),"/",compose("%.4f",stats.std_1));
                utils.updateTableByField(app,i,app.objList(1),obj1Label);
                if numObjectives >= 2
                    obj2Label = strcat(compose("%.4f",stats.mean_2),"/",compose("%.4f",stats.std_2));
                    utils.updateTableByField(app,i,app.objList(2),obj2Label);
                end
                if numObjectives == 3
                    obj3Label = strcat(compose("%.4f",stats.mean_3),"/",compose("%.4f",stats.std_3));
                    utils.updateTableByField(app,i,app.objList(3),obj3Label);
                end

                utils.updateTableByField(app,i,'ttot', stats.mean_ttot)
                
                % Update UITable 'status'
                utils.updateTableByField(app,i,'status',"Finished");

                % Store the results
                batch_results{i,1}.params = params(1,1:end);
                batch_results{i,1}.best_objectives = best_objectives;
                batch_results{i,1}.best_solutions = best_solutions;
                bestOfBests = utils.getBestSolution(best_solutions, best_objectives);
                batch_results{i,1}.best_chromosome = bestOfBests.best_solution;
                
                batch_results{i,1}.mean_obj1 = stats.mean_1;
                batch_results{i,1}.std_obj1 = stats.std_1;
                batch_results{i,1}.min_obj1 = stats.min_1;
                batch_results{i,1}.max_obj1 = stats.max_1;
                batch_results{i,1}.q1_obj1 = stats.q1_1;
                batch_results{i,1}.q3_obj1 = stats.q3_1;

                if numObjectives >= 2
                    batch_results{i,1}.mean_obj2 = stats.mean_2;
                    batch_results{i,1}.std_obj2 = stats.std_2;
                    batch_results{i,1}.min_obj2 = stats.min_2;
                    batch_results{i,1}.max_obj2 = stats.max_2;
                    batch_results{i,1}.q1_obj2 = stats.q1_2;
                    batch_results{i,1}.q3_obj2 = stats.q3_2;
                end
                if numObjectives == 3
                    batch_results{i,1}.mean_obj3 = stats.obj3_mean;
                    batch_results{i,1}.std_obj3 = stats.obj3_std;
                    batch_results{i,1}.min_obj3 = stats.min_3;
                    batch_results{i,1}.max_obj3 = stats.max_3;
                    batch_results{i,1}.q1_obj3 = stats.q1_3;
                    batch_results{i,1}.q3_obj3 = stats.q3_3;
                end
                batch_results{i,1}.mean_ttot = stats.mean_ttot;
                
                updateWaitbar(app, app.waitBar);
                numExecuted = numExecuted + 1;

                if app.isStopped
                    utils.log('[Batch] Execution stopped!');
                    break;
                end
            end
            
            utils.log('[Batch] Execution finished!');
            close(app.waitBar);

            cd('..');
            
            app.batchResults = batch_results;
            utils.exportResults(app, numExecuted);

            app.isStopped = false;
            app.RUNButton.Enable = "on";
            app.RUNButton_Batch.Enable = "on";
        end

        % Value changed function: GridSizeTextArea_Batch
        function GridSizeTextArea_BatchValueChanged(app, event)
            value = app.GridSizeTextArea_Batch.Value;
            n = height(value);
            a = zeros(n,2);
            for i = 1:n
                % Parse the grid size cells
                dim = split(string(value{i,1}), ",");
                if length(dim) == 2
                    a(i, 1) = str2double(dim(1,1));
                    a(i, 2) = str2double(dim(2,1));
                end
            end
            % Remove zeroed lines
            app.gridSizeBatch = a(any(a,2),:);
            utils.log('[Batch] Grid dimensions: ');
            utils.log(app.gridSizeBatch);
        end

        % Value changed function: NumberOfExecutionsEditField
        function NumberOfExecutionsEditFieldValueChanged(app, event)
            app.numExecutions = app.NumberOfExecutionsEditField.Value;
            utils.log(['[Batch] Number of executions: ' num2str(app.numExecutions)]);
        end

        % Value changed function: PopulationSizeTextArea_Batch
        function PopulationSizeTextArea_BatchValueChanged(app, event)
            value = app.PopulationSizeTextArea_Batch.Value;
            n = height(value);
            a = zeros(n, 1);
            % Convert population size array to double
            a(:) = str2double(value(:));
            % Remove zeroed lines
            app.popSizeBatch = a(any(a,2),:);
            utils.log('[Batch] Population size: ');
            utils.log(app.popSizeBatch);
        end

        % Value changed function: MutationRateTextArea
        function MutationRateTextAreaValueChanged(app, event)
            value = app.MutationRateTextArea.Value;
            n = height(value);
            a = zeros(n, 1);
            % Convert mutation rate array to double
            a(:) = str2double(value(:));
            % Remove zeroed lines
            app.mutationRateBatch = a(any(a,2),:);
            utils.log('[Batch] Mutation rate: ');
            utils.log(app.mutationRateBatch);
        end

        % Button pushed function: STOPButton_Batch
        function STOPButton_BatchPushed(app, event)
            app.isStopped = true;
        end

        % Value changed function: AlgorithmTextArea
        function AlgorithmTextAreaValueChanged(app, event)
            value = app.AlgorithmTextArea.Value;
            n = height(value);
            idx = 1;
            a = {};
            % Remove zeroed lines
            for i=1:n
                v = value{i,1};
                if ~isempty(v)
                    a{idx,1} = v;
                    idx = idx+1;
                end
            end
            app.algorithmsBatch = a;
            utils.log('[Batch] Algorithms: ');
            utils.log(app.algorithmsBatch);
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            utils.exportResults(app, 0);
        end

        % Button pushed function: ViewGraphButton
        function ViewGraphButtonPushed(app, event)
            viewGraph(app);
        end

        % Button pushed function: ViewGraphButton_2
        function ViewGraphButton_2Pushed(app, event)
            viewGraph(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            i = event.Indices;
            r = i(1);
            c = i(2);
            newData = event.NewData;
            selectIndex = utils.getEncodingTable(app.objList, 'select');

            if c == selectIndex
                app.UITable.Data(r,c) = newData;
            end
        end

        % Button pushed function: VisualizeSolutionButton
        function VisualizeSolutionButtonPushed(app, event)
            indices = app.UITable.Data(:,utils.getEncodingTable(app.objList, 'select'));
            selectedIndex = find(indices=="1")';

            elements = app.batchResults(selectedIndex,:);

            for i=1:height(elements)
                chromosome = elements{i}.best_chromosome{1,1};
                r = elements{i}.params{2};
                c = elements{i}.params{3};
                t = elements{i}.params{1};
                % Draw the chromosome solution
                utils.drawSolution([], r, c, t, chromosome, selectedIndex(i), true);
            end

        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            [app.algtype, app.algorithm, app.algorithmVariation] = utils.getAlgorithmDropDown(value);
            utils.log(['Type: ' app.algtype 'Algorithm: ' app.algorithm ' | Variation: ' num2str(app.algorithmVariation)]);
        end

        % Value changed function: MutationRateEditField
        function MutationRateEditFieldValueChanged(app, event)
            app.mutationRateSingle = app.MutationRateEditField.Value;
            utils.log(['Mutation Rate: ' num2str(app.mutationRateSingle)]);
        end

        % Value changed function: ApproachDropDown
        function ApproachDropDownValueChanged(app, event)
            app.approach = app.ApproachDropDown.Value;
            utils.log(['Approach: ' app.approach]);
        end

        % Selection changed function: ModeSelect
        function ModeSelectSelectionChanged(app, event)
            selectedButton = app.ModeSelect.SelectedObject;
            switch selectedButton.Text
                case 'Single'
                    app.applicationMode = 'SINGLE';
                case 'Multi'
                    app.applicationMode = 'MULTI';
                otherwise
                    app.applicationMode = 'SINGLE';
            end

            utils.log(['Applications Mode: ' app.applicationMode]);
        end

        % Selection changed function: ModeSelect_2
        function ModeSelect_2SelectionChanged(app, event)
            selectedButton = app.ModeSelect_2.SelectedObject;
            switch selectedButton.Text
                case 'Single'
                    app.applicationModeBatch = 'SINGLE';
                case 'Multi'
                    app.applicationModeBatch = 'MULTI';
                otherwise
                    app.applicationModeBatch = 'SINGLE';
            end

            utils.log(['[Batch] Applications Mode: ' app.applicationModeBatch]);
        end

        % Value changed function: ApproachTextArea
        function ApproachTextAreaValueChanged(app, event)
            value = app.ApproachTextArea.Value;
            n = height(value);
            idx = 1;
            a = {};
            % Remove zeroed lines
            for i=1:n
                v = value{i,1};
                if ~isempty(v)
                    a{idx,1} = v;
                    idx = idx+1;
                end
            end
            app.approachesBatch = a;
            utils.log('[Batch] Approaches: ');
            utils.log(app.approachesBatch);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCTORFrameworkUIFigure = uifigure('Visible', 'off');
            app.MCTORFrameworkUIFigure.Name = 'MCTOR Framework';
            app.TabGroup = uitabgroup(app.MCTORFrameworkUIFigure);
            app.Single = uitab(app.TabGroup);
            app.Single.Title = 'Single Mode';
            app.RightPanel = uipanel(app.Single);
            app.Graph = uiaxes(app.RightPanel);
            xlabel(app.Graph, 'Energy')
            ylabel(app.Graph, 'Load Balance')
            app.LeftPanel = uipanel(app.Single);
            app.RowsEditFieldLabel = uilabel(app.LeftPanel);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.ColumnsEditFieldLabel = uilabel(app.LeftPanel);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ModeSelect = uibuttongroup(app.LeftPanel);
            app.ModeSelect.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectSelectionChanged, true);
            app.MultiButton = uitogglebutton(app.ModeSelect);
            app.MultiButton.Text = 'Multi';
            app.SingleButton = uitogglebutton(app.ModeSelect);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.AlgorithmDropDown = uidropdown(app.LeftPanel);
            app.AlgorithmDropDown.Items = {'NSGAII', 'NSGAII_HR', 'NSGAII_HS', 'NSGAII_DR', 'NSGAII_DS', 'NSGAIII', 'NSGAIII_HR', 'NSGAIII_HS', 'NSGAIII_DR', 'NSGAIII_DS', 'PESAII', 'PESAII_HR', 'PESAII_HS', 'PESAII_DR', 'PESAII_DS', 'HR', 'HS', 'DR', 'DS'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Tooltip = {'Select the algorithm to be performed'};
            app.AlgorithmDropDown.Value = 'NSGAII';
            app.PopulationSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.NumberofObjectivesDropDownLabel = uilabel(app.LeftPanel);
            app.NumberofObjectivesDropDownLabel.Text = 'Number of Objectives';
            app.NumberOfObjectivesDropDown = uidropdown(app.LeftPanel);
            app.NumberOfObjectivesDropDown.Items = {'1.E', '2.E/LB', '2.E/FT'};
            app.NumberOfObjectivesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberOfObjectivesDropDownValueChanged, true);
            app.NumberOfObjectivesDropDown.Tooltip = {'Select the objectives to be optimized.'; 'E: Energy (Communication)'; 'LB: Load balance'; 'FT: Fault tolerance'};
            app.NumberOfObjectivesDropDown.Value = '2.E/LB';
            app.MutationRateEditFieldLabel = uilabel(app.LeftPanel);
            app.MutationRateEditFieldLabel.Text = 'Mutation Rate';
            app.MutationRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MutationRateEditField.Limits = [0 Inf];
            app.MutationRateEditField.ValueDisplayFormat = '%.3f';
            app.MutationRateEditField.ValueChangedFcn = createCallbackFcn(app, @MutationRateEditFieldValueChanged, true);
            app.MutationRateEditField.Value = 0.01;
            app.PopulationSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PopulationSizeEditField.Limits = [1 Inf];
            app.PopulationSizeEditField.ValueDisplayFormat = '%.0f';
            app.PopulationSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PopulationSizeEditFieldValueChanged, true);
            app.PopulationSizeEditField.Value = 100;
            app.ApproachDropDown = uidropdown(app.LeftPanel);
            app.ApproachDropDown.Items = {'Multi-Application', 'Simulated Annealing', 'Multi-Phase', 'Condor'};
            app.ApproachDropDown.ValueChangedFcn = createCallbackFcn(app, @ApproachDropDownValueChanged, true);
            app.ApproachDropDown.Value = 'Multi-Application';
            app.ApproachDropDownLabel = uilabel(app.LeftPanel);
            app.ApproachDropDownLabel.Text = 'Approach';
            app.ProblemPropertiesLabel = uilabel(app.LeftPanel);
            app.ProblemPropertiesLabel.Text = 'Problem Properties';
            app.ColumnsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueDisplayFormat = '%.0f';
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 4;
            app.RowsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueDisplayFormat = '%.0f';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @RowsEditFieldValueChanged, true);
            app.RowsEditField.Value = 4;
            app.GridSizeLabel = uilabel(app.LeftPanel);
            app.GridSizeLabel.Text = 'Grid Size';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.VersionLabel = uilabel(app.LeftPanel);
            app.VersionLabel.Text = 'v1.0 2023';
            app.SourceEditFieldLabel = uilabel(app.LeftPanel);
            app.SourceEditFieldLabel.Text = 'Source';
            app.TargetEditFieldLabel = uilabel(app.LeftPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.WeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.TasksAmountEditField = uieditfield(app.LeftPanel, 'text');
            app.TasksAmountEditField.ValueChangedFcn = createCallbackFcn(app, @TasksAmountEditFieldValueChanged, true);
            app.TasksAmountEditField.Editable = 'off';
            app.TasksAmountEditField.Value = '5';
            app.SourceEditField = uieditfield(app.LeftPanel, 'text');
            app.SourceEditField.ValueChangedFcn = createCallbackFcn(app, @SourceEditFieldValueChanged, true);
            app.SourceEditField.Editable = 'off';
            app.SourceEditField.Value = '1 1 2 3 3 4';
            app.TargetEditField = uieditfield(app.LeftPanel, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @TargetEditFieldValueChanged, true);
            app.TargetEditField.Editable = 'off';
            app.TargetEditField.Value = '2 3 4 4 5 5';
            app.WeightEditField = uieditfield(app.LeftPanel, 'text');
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Editable = 'off';
            app.WeightEditField.Value = '10 20 30 40 50 60';
            app.LoadFromFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed, true);
            app.LoadFromFileButton.Tooltip = {'Load aplications from .tgffopt and .app files'};
            app.LoadFromFileButton.Text = 'Load from file';
            app.GraphsInputLabel = uilabel(app.LeftPanel);
            app.GraphsInputLabel.Text = 'Graphs Input';
            app.ViewGraphButton = uibutton(app.LeftPanel, 'push');
            app.ViewGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGraphButtonPushed, true);
            app.ViewGraphButton.Tooltip = {'Visualize the loaded applications'};
            app.ViewGraphButton.Text = 'V';
            app.TasksAmountEditFieldLabel = uilabel(app.LeftPanel);
            app.TasksAmountEditFieldLabel.Text = 'Tasks Amount';
            app.AlgorithmDropDownLabel = uilabel(app.LeftPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.Batch = uitab(app.TabGroup);
            app.Batch.Title = 'Batch Mode';
            app.RightPanel_Batch = uipanel(app.Batch);
            app.UITable = uitable(app.RightPanel_Batch);
            app.UITable.ColumnName = {'Grid'; 'Pop.Size'; 'Mut.Rt.'; 'Algorithm'; 'Status'; 'Energy (Avg/Std)'; 'LoadBal (Avg/Std)'; 'Ttot'; 'Select'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ExportResultsButton = uibutton(app.RightPanel_Batch, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Tooltip = {'Export the current results to ''batch'' folder'};
            app.ExportResultsButton.Text = 'Export results';
            app.VisualizeSolutionButton = uibutton(app.RightPanel_Batch, 'push');
            app.VisualizeSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeSolutionButtonPushed, true);
            app.VisualizeSolutionButton.Tooltip = {'First, select a result in the results table to visualize the best individual'};
            app.VisualizeSolutionButton.Text = 'Visualize Solution';
            app.LeftPanel_Batch = uipanel(app.Batch);
            app.VersionLabel_2 = uilabel(app.LeftPanel_Batch);
            app.VersionLabel_2.Text = 'v1.0 2023';
            app.STOPButton_Batch = uibutton(app.LeftPanel_Batch, 'push');
            app.STOPButton_Batch.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_BatchPushed, true);
            app.STOPButton_Batch.Text = 'STOP';
            app.RUNButton_Batch = uibutton(app.LeftPanel_Batch, 'push');
            app.RUNButton_Batch.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_BatchPushed, true);
            app.RUNButton_Batch.Text = 'RUN';
            app.AlgorithmTextArea = uitextarea(app.LeftPanel_Batch);
            app.AlgorithmTextArea.ValueChangedFcn = createCallbackFcn(app, @AlgorithmTextAreaValueChanged, true);
            app.AlgorithmTextArea.Tooltip = {'List of the algorithms to be tested'; 'Format: {Algorithm}_V{Version}'};
            app.AlgorithmTextArea.Value = {'NSGAII'};
            app.MutationRateTextArea = uitextarea(app.LeftPanel_Batch);
            app.MutationRateTextArea.ValueChangedFcn = createCallbackFcn(app, @MutationRateTextAreaValueChanged, true);
            app.MutationRateTextArea.Tooltip = {'List of the mutation rates to be tested in decimal format'};
            app.MutationRateTextArea.Value = {'0.01'};
            app.PopulationSizeTextArea_Batch = uitextarea(app.LeftPanel_Batch);
            app.PopulationSizeTextArea_Batch.ValueChangedFcn = createCallbackFcn(app, @PopulationSizeTextArea_BatchValueChanged, true);
            app.PopulationSizeTextArea_Batch.Tooltip = {'The number of individuals in the population'};
            app.PopulationSizeTextArea_Batch.Value = {'100'};
            app.ApproachTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.ApproachTextAreaLabel.Text = 'Approach';
            app.ApproachTextArea = uitextarea(app.LeftPanel_Batch);
            app.ApproachTextArea.ValueChangedFcn = createCallbackFcn(app, @ApproachTextAreaValueChanged, true);
            app.ApproachTextArea.Value = {'Multi-Application'};
            app.MutationrateTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.MutationrateTextAreaLabel.Text = 'Mutation rate';
            app.PopulationSizeEditField_2Label_2 = uilabel(app.LeftPanel_Batch);
            app.PopulationSizeEditField_2Label_2.Text = 'Number of executions';
            app.NumberOfExecutionsEditField = uieditfield(app.LeftPanel_Batch, 'numeric');
            app.NumberOfExecutionsEditField.Limits = [1 Inf];
            app.NumberOfExecutionsEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfExecutionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfExecutionsEditFieldValueChanged, true);
            app.NumberOfExecutionsEditField.Tooltip = {'Number of executions for each set of parameters'};
            app.NumberOfExecutionsEditField.Value = 50;
            app.RowsColumnsTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.RowsColumnsTextAreaLabel.Text = 'Rows,Columns';
            app.PopulationsizeLabel = uilabel(app.LeftPanel_Batch);
            app.PopulationsizeLabel.Text = 'Population size';
            app.AlgorithmTextAreaLabel = uilabel(app.LeftPanel_Batch);
            app.AlgorithmTextAreaLabel.Text = 'Algorithm';
            app.GridSizeTextArea_Batch = uitextarea(app.LeftPanel_Batch);
            app.GridSizeTextArea_Batch.ValueChangedFcn = createCallbackFcn(app, @GridSizeTextArea_BatchValueChanged, true);
            app.GridSizeTextArea_Batch.Tooltip = {'List of the grid sizes to bested in format R, C'};
            app.GridSizeTextArea_Batch.Value = {'4,4'; '6,6'; '8,8'};
            app.ModeSelect_2 = uibuttongroup(app.LeftPanel_Batch);
            app.ModeSelect_2.SelectionChangedFcn = createCallbackFcn(app, @ModeSelect_2SelectionChanged, true);
            app.MultiButton_2 = uitogglebutton(app.ModeSelect_2);
            app.MultiButton_2.Text = 'Multi';
            app.SingleButton_2 = uitogglebutton(app.ModeSelect_2);
            app.SingleButton_2.Text = 'Single';
            app.SingleButton_2.Value = true;
            app.BatchParametersLabel = uilabel(app.LeftPanel_Batch);
            app.BatchParametersLabel.Text = 'Batch Parameters';
            app.ViewGraphButton_2 = uibutton(app.LeftPanel_Batch, 'push');
            app.ViewGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewGraphButton_2Pushed, true);
            app.ViewGraphButton_2.Tooltip = {'Visualize the loaded applications'};
            app.ViewGraphButton_2.Text = 'V';
            app.LoadFromFileButton_2 = uibutton(app.LeftPanel_Batch, 'push');
            app.LoadFromFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButton_2Pushed, true);
            app.LoadFromFileButton_2.Tooltip = {'Load aplications from .tgffopt and .app files'};
            app.LoadFromFileButton_2.Text = 'Load from file';
            app.WeightEditField_2 = uieditfield(app.LeftPanel_Batch, 'text');
            app.WeightEditField_2.Editable = 'off';
            app.WeightEditField_2.Value = '10 20 30 40 50 60';
            app.WeightEditFieldLabel_2 = uilabel(app.LeftPanel_Batch);
            app.WeightEditFieldLabel_2.Text = 'Weight';
            app.TargetEditField_2 = uieditfield(app.LeftPanel_Batch, 'text');
            app.TargetEditField_2.Editable = 'off';
            app.TargetEditField_2.Value = '2 3 4 4 5 5';
            app.TargetEditFieldLabel_2 = uilabel(app.LeftPanel_Batch);
            app.TargetEditFieldLabel_2.Text = 'Target';
            app.SourceEditField_2 = uieditfield(app.LeftPanel_Batch, 'text');
            app.SourceEditField_2.Editable = 'off';
            app.SourceEditField_2.Value = '1 1 2 3 3 4';
            app.SourceEditFieldLabel_2 = uilabel(app.LeftPanel_Batch);
            app.SourceEditFieldLabel_2.Text = 'Source';
            app.TasksAmountEditField_2 = uieditfield(app.LeftPanel_Batch, 'text');
            app.TasksAmountEditField_2.Editable = 'off';
            app.TasksAmountEditField_2.Value = '5';
            app.TasksAmountEditFieldLabel_2 = uilabel(app.LeftPanel_Batch);
            app.TasksAmountEditFieldLabel_2.Text = 'Tasks Amount';
            app.GraphsInputLabel_2 = uilabel(app.LeftPanel_Batch);
            app.GraphsInputLabel_2.Text = 'Graphs Input';
            app.AboutUsTab = uitab(app.TabGroup);
            app.AboutUsTab.Title = 'About Us';
            app.VersionsList = uilabel(app.AboutUsTab);
            app.VersionsList.Text = {'MCTOR v1.0'; 'Matlab R2023a'; 'PlatEMO v4.1'};
            app.SoftwareVersionLabel = uilabel(app.AboutUsTab);
            app.SoftwareVersionLabel.Text = 'Software Version';
            app.AuthorsList = uilabel(app.AboutUsTab);
            app.AuthorsList.Text = {'Igor Felipe Gallon - Computer Science Dept., UFSCar, S. Carlos, SP, Brazil'; 'Manoel A. Almeida - Computer Science Dept., UFSCar, S. Carlos, SP, Brazil'; 'Emerson Carlos Pedrino - Computer Science Dept., UFSCar, S. Carlos, SP, Brazil'};
            app.AuthorsLabel = uilabel(app.AboutUsTab);
            app.AuthorsLabel.Text = 'Authors';
            app.Title = uilabel(app.AboutUsTab);
            app.Title.Text = 'MCTOR: A Framework for Tasks routing in Many-Core Architectures';
            % Show the figure after all components are created
            app.MCTORFrameworkUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mctor_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCTORFrameworkUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCTORFrameworkUIFigure)
        end
    end
end